classdef MATLAB_Blockchain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StepBy_StepInstructionsButton  matlab.ui.control.Button
        MineBlockButton                matlab.ui.control.Button
        PrintClientSetButton           matlab.ui.control.Button
        IoTDevicePort2EditField        matlab.ui.control.NumericEditField
        IoTDevicePort2EditFieldLabel   matlab.ui.control.Label
        IoTDeviceoneEditField          matlab.ui.control.NumericEditField
        IoTDeviceoneEditFieldLabel     matlab.ui.control.Label
        AddressEditField               matlab.ui.control.EditField
        AddressEditFieldLabel          matlab.ui.control.Label
        AddClientButton                matlab.ui.control.Button
        MATLABBlockchainDemoLabel      matlab.ui.control.Label
        DataEditField                  matlab.ui.control.EditField
        DataEditFieldLabel             matlab.ui.control.Label
        AddBlockButton                 matlab.ui.control.Button
        RegistrationButton             matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        run = false;% Description
        AddBlock = false;
        AddClient = false;
        blockchain = bc.Blockchain();
        nodes = p2p.ClientNodeSet();
        q
        Mining = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('ml_coin.png','Parent',app.UIAxes);
            app.StopButton.Enable = false;
            app.q = parallel.pool.PollableDataQueue;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StopButton.Enable = true;
            app.StartButton.Enable = false;
            app.run = true;
            counter = 0;
            while app.run
                % Check GUI actions
                if app.Mining
                    fprintf('x');
                    [block, gotMsg] = poll(app.q, 1);
                    if gotMsg
                        added = app.blockchain.add_mined_block(block);
                        if added
                            app.nodes.broadcast(p2p.MessageType.RESPONSE_BLOCKCHAIN, jsonencode(block));
                        end
                        app.Mining = false;
                    end
                end
                
                if app.AddBlock
                    block = app.blockchain.add_block(app.DataEditField.Value, uint32(6));
                    app.nodes.broadcast(p2p.MessageType.RESPONSE_BLOCKCHAIN, jsonencode(block));
                    app.AddBlock = false;
                end
                
                if app.AddClient
                    disp('### Adding Client');
                    node = app.nodes.add_client(app.AddressEditField.Value, app.IoTDeviceoneEditField.Value, app.IoTDevicePort2EditField.Value);
                    disp('### After Adding Client');
                    if ~isempty(node)
                        try
                            disp('### Adding Client transmit');
                            node.transmit(p2p.MessageType.QUERY_LATEST, []);
                            disp('### After Adding Client transmit');
                        catch ME
                            disp('*** Node not replying');
                        end
                    end
                    app.AddClient = false;
                end
                
                % Check if client wants to communicate
                rnodes = app.nodes.select();
                for idx=1:numel(rnodes)
                    disp('### Inside for');
                    [cmd, mess] = rnodes{idx}.receive();
                    disp(['### recievce ', num2str(cmd), ' ', mess]);
                    
                    %                     QUERY_LATEST        (0)
                    %                     QUERY_ALL           (1)
                    %                     RESPONSE_BLOCKCHAIN (2)
                    %                     DO_NOTHING          (3)
                    %                     BROADCAST_LATEST    (4)
                    
                    % FIXME: set flags and do comms here
                    
                    switch cmd
                        case p2p.MessageType.QUERY_LATEST
                            block = app.blockchain.blockchain(end);
                            rnodes{idx}.transmit(p2p.MessageType.RESPONSE_BLOCKCHAIN, jsonencode(block));
                        case p2p.MessageType.QUERY_ALL
                            rnodes{idx}.transmit(p2p.MessageType.RESPONSE_BLOCKCHAIN, jsonencode(app.blockchain.blockchain));
                        case p2p.MessageType.RESPONSE_BLOCKCHAIN
                            disp('### RESP_BLOCKCHAIN');
                            rv = app.blockchain.handle_blockchain_response(jsondecode(mess));
                            disp('### RESP_BLOCKCHAIN after handle');
                            switch rv
                                case p2p.MessageType.BROADCAST_LATEST
                                    disp('### BRDCAST LATEST');
                                    block = app.blockchain.blockchain(end);
                                    app.nodes.broadcast(p2p.MessageType.RESPONSE_BLOCKCHAIN, jsonencode(block));
                                case p2p.MessageType.QUERY_ALL
                                    disp('### QEWRY ALL');
                                    app.nodes.broadcast(p2p.MessageType.QUERY_ALL, []);
                                case p2p.MessageType.DO_NOTHING
                                    disp('### DO NOTHING');
                                    % Do nothing
                                otherwise
                                    disp(['*** ERROR wrong rv : ', num2str(rv)]);
                            end
                        otherwise
                            disp(['*** ERROR wrong command : ', cmd]);
                    end
                end
                pause(1);
                fprintf('.');
                counter = counter + 1;
                if counter > 25
                    fprintf('\n');
                    counter = 0;
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = false;
            app.StartButton.Enable = true;
            app.run = false;
        end

        % Button pushed function: RegistrationButton
        function RegistrationButtonPushed(app, event)
            app.blockchain.print();
        end

        % Button pushed function: AddBlockButton
        function AddBlockButtonPushed(app, event)
            if app.AddBlock
                disp('*** Take it easy dude, I''m working...');
            else
                app.AddBlock = true;
            end
            
            %             block = app.blockchain.add_block(app.DataEditField.Value);
            %             app.nodes.broadcast(p2p.MessageType.RESPONSE_BLOCKCHAIN, jsonencode(block));
        end

        % Button pushed function: AddClientButton
        function AddClientButtonPushed(app, event)
            if app.AddClient
                disp('*** Take it easy dude, I''m working...');
            else
                app.AddClient = true;
            end
            
            %             node = app.nodes.add_client(app.AddressEditField.Value, app.IoTDeviceoneEditField.Value, app.IoTDevicePort2EditField.Value);
            %             if ~isempty(node)
            %                 try
            %                     node.transmit(p2p.MessageType.QUERY_LATEST, []);
            %                 catch ME
            %                     disp('*** Node not replying');
            %                 end
            %             end
        end

        % Button pushed function: PrintClientSetButton
        function PrintClientSetButtonPushed(app, event)
            app.nodes.print();
        end

        % Button pushed function: MineBlockButton
        function MineBlockButtonPushed(app, event)
            if app.Mining
                disp('*** Take it easy dude, I''m working...');
            else
                
                app.Mining = true;
                index = app.blockchain.blockchain(end).index + 1;
                prev_hash = app.blockchain.blockchain(end).hash;
                timestamp = char(datetime);
                
                parfeval(@bc.mine2, 0, app.q, index, prev_hash, timestamp, app.DataEditField.Value);
            end
        end

        % Button pushed function: StepBy_StepInstructionsButton
        function StepBy_StepInstructionsButtonPushed(app, event)
            [a, ~, ~] = fileparts(mfilename('fullpath'));
            sbs_file = fullfile(a, '..', 'doc', 'sbs.txt');
            evalin('base', ['edit ', sbs_file]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RegistrationButton = uibutton(app.UIFigure, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationButtonPushed, true);
            app.RegistrationButton.Text = 'Registration';
            app.AddBlockButton = uibutton(app.UIFigure, 'push');
            app.AddBlockButton.ButtonPushedFcn = createCallbackFcn(app, @AddBlockButtonPushed, true);
            app.AddBlockButton.Text = 'Add Block';
            app.DataEditFieldLabel = uilabel(app.UIFigure);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.DataEditField.Value = '7744';
            app.MATLABBlockchainDemoLabel = uilabel(app.UIFigure);
            app.MATLABBlockchainDemoLabel.Text = 'MATLAB Blockchain Demo';
            app.AddClientButton = uibutton(app.UIFigure, 'push');
            app.AddClientButton.ButtonPushedFcn = createCallbackFcn(app, @AddClientButtonPushed, true);
            app.AddClientButton.Text = 'Add Client';
            app.AddressEditFieldLabel = uilabel(app.UIFigure);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.UIFigure, 'text');
            app.AddressEditField.Value = 'localhost';
            app.IoTDeviceoneEditFieldLabel = uilabel(app.UIFigure);
            app.IoTDeviceoneEditFieldLabel.Text = 'IoT Device one';
            app.IoTDeviceoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.IoTDeviceoneEditField.Value = 8866;
            app.IoTDevicePort2EditFieldLabel = uilabel(app.UIFigure);
            app.IoTDevicePort2EditFieldLabel.Text = 'IoT Device Port 2';
            app.IoTDevicePort2EditField = uieditfield(app.UIFigure, 'numeric');
            app.IoTDevicePort2EditField.Value = 8844;
            app.PrintClientSetButton = uibutton(app.UIFigure, 'push');
            app.PrintClientSetButton.ButtonPushedFcn = createCallbackFcn(app, @PrintClientSetButtonPushed, true);
            app.PrintClientSetButton.Text = 'Print Client Set';
            app.MineBlockButton = uibutton(app.UIFigure, 'push');
            app.MineBlockButton.ButtonPushedFcn = createCallbackFcn(app, @MineBlockButtonPushed, true);
            app.MineBlockButton.Text = 'Mine Block';
            app.StepBy_StepInstructionsButton = uibutton(app.UIFigure, 'push');
            app.StepBy_StepInstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @StepBy_StepInstructionsButtonPushed, true);
            app.StepBy_StepInstructionsButton.Text = 'Step-By_Step Instructions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_Blockchain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end